classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        GuardarButton              matlab.ui.control.Button
        FechaPartoDatePicker       matlab.ui.control.DatePicker
        FechaPartoDatePickerLabel  matlab.ui.control.Label
        EdadEditField              matlab.ui.control.NumericEditField
        EdadLabel                  matlab.ui.control.Label
        NombreEditField            matlab.ui.control.EditField
        NOMBRELabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            function GuardarButtonPushed(app, event)
    nombre = app.NombreEditField.Value;
    edad = app.EdadEditField.Value;
    fechaParto = app.FechaPartoDatePicker.Value;

    nuevoRegistro = {nombre, edad, datestr(fechaParto)};
    
    if isempty(app.UITable.Data)
        app.UITable.Data = nuevoRegistro;
    else
        app.UITable.Data = [app.UITable.Data; nuevoRegistro];
    end
            end
            function ExportarButtonPushed(app, event)
    datos = app.UITable.Data;
    encabezado = {'Nombre', 'Edad', 'FechaParto'};
    datosTabla = [encabezado; datos];
    writecell(datosTabla, 'embarazadas.xlsx');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NOMBRELabel = uilabel(app.UIFigure);
            app.NOMBRELabel.Text = 'NOMBRE';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.EdadLabel = uilabel(app.UIFigure);
            app.EdadLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.FechaPartoDatePickerLabel = uilabel(app.UIFigure);
            app.FechaPartoDatePickerLabel.Text = 'FechaParto';
            app.FechaPartoDatePicker = uidatepicker(app.UIFigure);
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end