classdef proyectosaludapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PrediccindePesoButton     matlab.ui.control.Button
        Image3                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        MedirTallaCamaraButton    matlab.ui.control.Button
        VERDATOSButton            matlab.ui.control.Button
        GuardarButton             matlab.ui.control.Button
        ResultadosEditField       matlab.ui.control.EditField
        ResultadosEditFieldLabel  matlab.ui.control.Label
        CalcularButton            matlab.ui.control.Button
        SexoDropDown              matlab.ui.control.DropDown
        SexoDropDownLabel         matlab.ui.control.Label
        CUIEditField              matlab.ui.control.EditField
        CUIEditFieldLabel         matlab.ui.control.Label
        NombreEditField           matlab.ui.control.EditField
        NombreEditFieldLabel      matlab.ui.control.Label
        TallaEditField            matlab.ui.control.NumericEditField
        TallaEditFieldLabel       matlab.ui.control.Label
        PesoLbEditField           matlab.ui.control.NumericEditField
        PesoLbEditFieldLabel      matlab.ui.control.Label
        EdadEditField             matlab.ui.control.NumericEditField
        EdadEditFieldLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

properties (Access = private)
    conn  % Propiedad para la conexión a la base de datos
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               try
        app.conn = abrirConexionPostgres();
        disp('Conexión abierta');
    catch ME
        uialert(app.UIFigure, ME.message, 'Error conexión');
               end
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed2(app, event)
try
    edadSemanas = app.EdadEditField.Value;
    sexo = app.SexoDropDown.Value;
    pesoLb = app.PesoLbEditField.Value;
    tallaCm = app.TallaEditField.Value;

    rutaExcel = 'C:\Users\USER\OneDrive\Documentos\MATLAB\proyectosalud';

    % Llamar función externa
    [~, z, ~] = calcularEstadoNutricional(edadSemanas, sexo, pesoLb, tallaCm, rutaExcel);

    % Clasificación según z-score y color
    if z < -3
        estado = 'Desnutrición severa';
        color = [0.8 0 0];          % rojo fuerte
    elseif z >= -3 && z < -2
        estado = 'Desnutrición moderada';
        color = [0.9 0.4 0];        % naranja fuerte
    elseif z >= -2 && z < -1
        estado = 'Riesgo nutricional';
        color = [0.95 0.7 0.2];     % amarillo oscuro
    elseif z >= -1 && z <= 1
        estado = 'Normal';
        color = [0.2 0.7 0.2];      % verde
    elseif z > 1 && z <= 2
        estado = 'Sobrepeso';
        color = [0.95 0.7 0.2];     % amarillo oscuro
    elseif z > 2
        estado = 'Obesidad';
        color = [0.8 0 0];          % rojo fuerte
    else
        estado = 'Estado desconocido';
        color = [0.5 0.5 0.5];      % gris neutro
    end

    % Mostrar estado y cambiar color de fondo del campo resultado
    app.ResultadosEditField.Value = estado;
    app.ResultadosEditField.BackgroundColor = color;

    % Graficar z-score con barra del mismo color
    bar(app.UIAxes, z, 'FaceColor', color)
    ylim(app.UIAxes, [-5 5])
    title(app.UIAxes, 'Z-score IMC')
    ylabel(app.UIAxes, 'Z-score')
    grid(app.UIAxes, 'on')

catch ME
    disp(ME.message)
end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
             dbname = 'proyectosalud1';
    username = 'proyectosalud1_user';
    password = 'wKwT0xKbup5FwyDZLoJSt0gnwXUN9XxK';
    server = 'dpg-d0mjt7e3jp1c738doscg-a.oregon-postgres.render.com';
    port = 5432;

    % Crear conexión
    conn = database(dbname, username, password, ...
        'Vendor', 'PostgreSQL', ...
        'Server', server, ...
        'PortNumber', port);

    if ~isopen(conn)
        uialert(app.UIFigure, 'No se pudo conectar a la base de datos.', 'Error conexión');
        return
    end

    % Leer datos UI
    cui = app.CUIEditField.Value;
    nombre = app.NombreEditField.Value;
    edad = app.EdadEditField.Value;
    pesoLb = app.PesoLbEditField.Value;
    tallaCm = app.TallaEditField.Value;
    sexo = app.SexoDropDown.Value;
    rutaExcel = 'C:\Users\USUARIO\Documents\proyectosalud'; % ajusta si cambia

    % Validar que campos no estén vacíos (simple ejemplo)
    if isempty(cui) || isempty(nombre)
        uialert(app.UIFigure, 'CUI y Nombre son obligatorios.', 'Datos incompletos');
        close(conn);
        return
    end

    % Calcular estado nutricional
    try
        [~, ~, estado] = calcularEstadoNutricional(edad, sexo, pesoLb, tallaCm, rutaExcel);
    catch ME
        uialert(app.UIFigure, ['Error calculando estado: ', ME.message], 'Error');
        close(conn);
        return
    end

    % SQL UPSERT (insertar o actualizar según CUI)
    sqlquery = sprintf([ ...
        'INSERT INTO pacientes (CUI, Nombre, Edad, PesoLb, Talla, Sexo, EstadoNutricional, fecha_guardado) ', ...
        'VALUES (''%s'', ''%s'', %d, %f, %f, ''%s'', ''%s'', CURRENT_TIMESTAMP) ', ...
        'ON CONFLICT (CUI) DO UPDATE SET ', ...
        'Nombre = EXCLUDED.Nombre, Edad = EXCLUDED.Edad, PesoLb = EXCLUDED.PesoLb, Talla = EXCLUDED.Talla, ', ...
        'Sexo = EXCLUDED.Sexo, EstadoNutricional = EXCLUDED.EstadoNutricional, fecha_guardado = CURRENT_TIMESTAMP;'], ...
        cui, nombre, edad, pesoLb, tallaCm, sexo, estado);

    try
        exec(conn, sqlquery);
        uialert(app.UIFigure, 'Datos guardados correctamente.', 'Éxito');
    catch ME
        uialert(app.UIFigure, ['Error guardando datos: ', ME.message], 'Error');
    end

    close(conn);
        end

        % Button pushed function: VERDATOSButton
        function VERDATOSButtonPushed(app, event)
  % Verifica conexión activa
    if isempty(app.conn) || ~isopen(app.conn)
        uialert(app.UIFigure, 'No hay conexión activa a la base de datos.', 'Error');
        return
    end

    % Consulta para obtener datos
    query = 'SELECT * FROM pacientes ORDER BY fecha_guardado DESC';

    try
        data = fetch(app.conn, query);
    catch ME
        uialert(app.UIFigure, ['Error al obtener datos: ', ME.message], 'Error');
        return
    end

    if isempty(data)
        uialert(app.UIFigure, 'No hay datos para mostrar.', 'Aviso');
        return
    end

    % Crear nueva ventana para mostrar datos y reportes
    f = uifigure('Name', 'Datos Guardados y Reporte', 'Position', [100 100 900 600]);

    % Tabla para mostrar datos
    t = uitable(f);
    t.Data = data;
    t.Position = [20 200 860 370];
    t.ColumnName = data.Properties.VariableNames;

    % Crear ejes para el gráfico
    ax = uiaxes(f);
    ax.Position = [50 20 800 160];
    title(ax, 'Distribución Estado Nutricional')
    xlabel(ax, 'Estado Nutricional')
    ylabel(ax, 'Cantidad')

    % Botón para generar reporte (gráfico)
    btn = uibutton(f, 'push', 'Text', 'Generar Reporte');
    btn.Position = [750 570 130 30];

    % Callback del botón generar reporte
    btn.ButtonPushedFcn = @(~,~) mostrarOpcionesReporte(app, data);
            
            function mostrarOpcionesReporte(app, data)
                figure(app.UIFigure);
                drawnow;
                
                selection = uiconfirm(app.UIFigure, ...
                    '¿En qué formato deseas guardarlo?', ...
                    'Elegir formato', ...
                    'Options', {'Excel', 'PDF', 'Cancelar'}, ...
                    'CancelOption', 'Cancelar');
                switch selection
                    case 'Excel'
                        [archivo, ruta] = uiputfile('*.xlsx', 'Guardar reporte');
                        if isequal(archivo,0)
                            return; 
                        end
                        
                        try
                            writetable(data, fullfile(ruta, archivo));
                            uialert(app.UIFigure, 'Reporte Excel guardado exitosamente', 'Éxito');
                        catch ME
                            uialert(app.UIFigure, ['No se pudo guardar', ME.message]);
                        end
                    
                    case 'PDF'
                        [archivo, ruta] = uiputfile('*.pdf', 'Guardar reporte');
                        if isequal(archivo, 0)
                            return;
                        end

                        try
                            fig = figure('Visible', 'off', 'Position', [100, 100, 800, 600]);
                            
                            uit = uitable(fig, ...
                                'Data', table2cell(data), ...
                                'ColumnName', data.Properties.VariableNames, ...
                                'Units', 'normalized', ...
                                'Position', [0.03 0.05 0.94 0.9], ...
                                'ColumnWidth', {100, 150, 50, 50, 50, 80, 100, 150});
                            drawnow;

                            frame = getframe(fig);
                            imagen = frame.cdata;
    
                            fig2 = figure('Visible', 'off');
                            imshow(imagen, 'Border', 'tight');
    
                            exportgraphics(fig2, fullfile(ruta, archivo), 'ContentType', 'vector');
                            close(fig2);
                            close(fig);

                            uialert(app.UIFigure, 'Reporte PDF guardado exitosamente', 'Éxito');

                        catch ME
                            uialert(app.UIFigure, ['No se pudo guardar ', ME.message], 'Error');

                        end
                        
                    otherwise
                        return
                end
            end 
        end

% Función local para generar reporte (puedes ponerla dentro de la clase o fuera)

function generarReporte(ax, data)
    estados = data.EstadoNutricional;
    estadosCat = categorical(estados);
    counts = countcats(estadosCat);
    categorias = categories(estadosCat);

    bar(ax, counts)
    ax.XTickLabel = categorias;
    ax.XTickLabelRotation = 45;
    grid(ax, 'on');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: MedirTallaCamaraButton
        function MedirTallaCamaraButtonPushed(app, event)
       tallaCm = medirTallaCamara();
    if ~isnan(tallaCm)
        app.TallaEditField.Value = tallaCm;
        uialert(app.UIFigure, ['Talla estimada: ', num2str(tallaCm), ' cm'], 'Medición Exitosa');
    else
        uialert(app.UIFigure, 'No se pudo medir la talla.', 'Error');
    end
        end

        % Callback function: not associated with a component
        function QUEHACERTextAreaValueChanging(app, event)
  texto = lower(event.Value);  % convierte a minúsculas para evitar fallos por mayúsculas
    
    if contains(texto, 'obeso')
        consejo = 'Consejo: Mantén una dieta balanceada y consulta a un especialista.';
    elseif contains(texto, 'desnutrido')
        consejo = 'Consejo: Aumenta ingesta calórica y busca apoyo nutricional.';
    elseif contains(texto, 'normal')
        consejo = 'Consejo: Continúa con hábitos saludables y ejercicios regulares.';
    elseif contains(texto, 'sobrepeso')
        consejo = 'Consejo: Controla las porciones y mantente activo físicamente.';
    else
        consejo = 'Escribe tu estado para recibir un consejo personalizado.';
    end
    
    app.LabelConsejo.Text = consejo;  % supongamos tienes un Label llamado LabelConsejo para mostrar consejos
        end

        % Button pushed function: PrediccindePesoButton
        function PrediccindePesoButtonPushed(app, event)
              edades = [10 20 30 40 50];   % edad en semanas
    pesos = [5.0 6.2 7.0 8.1 9.0]; % peso en libras

    % Llama a la función que abre la ventana con el gráfico y predicción
    abrirVentanaPrediccionPeso(app, edades, pesos);
        end
    function abrirVentanaPrediccionPeso(app, edades, pesos)
        f = uifigure('Name', 'Predicción Evolución de Peso', 'Position', [200 200 600 400]);

        ax = uiaxes(f);
        ax.Position = [50 100 500 250];

        grado = 2;
        p = polyfit(edades, pesos, grado);

        x_fit = linspace(min(edades), max(edades) + 10, 100);
        y_fit = polyval(p, x_fit);

        y_pred_train = polyval(p, edades);
        residuos = pesos - y_pred_train;
        sigma = std(residuos);

        y_upper = y_fit + 2*sigma;
        y_lower = y_fit - 2*sigma;

        plot(ax, edades, pesos, 'bo', 'MarkerSize', 8, 'DisplayName', 'Datos reales')
        hold(ax, 'on')
        plot(ax, x_fit, y_fit, 'r-', 'LineWidth', 2, 'DisplayName', 'Ajuste polinómico')
        fill(ax, [x_fit fliplr(x_fit)], [y_upper fliplr(y_lower)], ...
            [1 0.8 0.8], 'EdgeColor', 'none', 'DisplayName', 'Banda confianza ±2σ')
        hold(ax, 'off')

        grid(ax, 'on')
        xlabel(ax, 'Edad (semanas)')
        ylabel(ax, 'Peso (lb)')
        title(ax, 'Predicción evolución peso con regresión polinómica')
        legend(ax, 'Location', 'northwest')

        edad_futura = max(edades) + 10;
        peso_futuro = polyval(p, edad_futura);

        uilabel(f, 'Position', [50 30 500 40], 'FontSize', 14, 'FontWeight', 'bold', ...
            'Text', sprintf('Predicción para edad %d semanas: %.2f lb (± %.2f lb)', ...
            round(edad_futura), peso_futuro, 2*sigma));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.PesoLbEditFieldLabel = uilabel(app.UIFigure);
            app.PesoLbEditFieldLabel.Text = 'Peso Lb';
            app.PesoLbEditField = uieditfield(app.UIFigure, 'numeric');
            app.TallaEditFieldLabel = uilabel(app.UIFigure);
            app.TallaEditFieldLabel.Text = 'Talla';
            app.TallaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.CUIEditFieldLabel = uilabel(app.UIFigure);
            app.CUIEditFieldLabel.Text = 'CUI';
            app.CUIEditField = uieditfield(app.UIFigure, 'text');
            app.SexoDropDownLabel = uilabel(app.UIFigure);
            app.SexoDropDownLabel.Text = 'Sexo';
            app.SexoDropDown = uidropdown(app.UIFigure);
            app.SexoDropDown.Items = {'Masculino', 'Femenino'};
            app.SexoDropDown.Value = 'Masculino';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed2, true);
            app.CalcularButton.Icon = fullfile(pathToMLAPP, 'iconos', 'images.png');
            app.CalcularButton.Text = 'Calcular';
            app.ResultadosEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadosEditFieldLabel.Text = 'Resultados';
            app.ResultadosEditField = uieditfield(app.UIFigure, 'text');
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Icon = fullfile(pathToMLAPP, 'iconos', 'descarga (4).png');
            app.GuardarButton.Text = 'Guardar';
            app.VERDATOSButton = uibutton(app.UIFigure, 'push');
            app.VERDATOSButton.ButtonPushedFcn = createCallbackFcn(app, @VERDATOSButtonPushed, true);
            app.VERDATOSButton.Icon = fullfile(pathToMLAPP, 'iconos', 'lupa.png');
            app.VERDATOSButton.Text = 'VER DATOS';
            app.MedirTallaCamaraButton = uibutton(app.UIFigure, 'push');
            app.MedirTallaCamaraButton.ButtonPushedFcn = createCallbackFcn(app, @MedirTallaCamaraButtonPushed, true);
            app.MedirTallaCamaraButton.Text = 'Medir Talla Camara';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconos', 'descarga (1).png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'myf.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'iconos', 'descarga (2).png');
            app.PrediccindePesoButton = uibutton(app.UIFigure, 'push');
            app.PrediccindePesoButton.ButtonPushedFcn = createCallbackFcn(app, @PrediccindePesoButtonPushed, true);
            app.PrediccindePesoButton.Icon = fullfile(pathToMLAPP, 'iconos', 'descarga.jpg');
            app.PrediccindePesoButton.Text = 'Predicción de Peso';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectosaludapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end